classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelNumericEditField  matlab.ui.control.Label            % weight(...
        NumericEditField       matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % heighr(...
        NumericEditField2      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % BMI
        NumericEditField3      matlab.ui.control.NumericEditField % [-Inf Inf]
        Button                 matlab.ui.control.Button           % calculate
        LabelEditField         matlab.ui.control.Label            % comment
        EditField              matlab.ui.control.EditField       
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            weight=app.NumericEditField.Value;
            height=app.NumericEditField2.Value;
            c=703*weight/(height*height)
            app.NumericEditField3.Value=c;
            if(app.NumericEditField3.Value<18.5)
                app.EditField.Value='Underweight'
            else if(app.NumericEditField3.Value>25)
                app.EditField.Value='Overweight'
                else if(app.NumericEditField3.Value>18.5 && app.NumericEditField3.Value<25)
                app.EditField.Value='Normal'
                end
            end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'weight(lbs)';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'heighr(inch)';
            app.NumericEditField2 = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'BMI';
            app.NumericEditField3 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'calculate';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'comment';
            app.EditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end